classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        minButton              matlab.ui.control.Button
        secButton              matlab.ui.control.Button
        WakeButton_2           matlab.ui.control.Button
        REMButton_2            matlab.ui.control.Button
        SWSButton_2            matlab.ui.control.Button
        ISButton               matlab.ui.control.Button
        UIEEG                  matlab.ui.control.UIAxes
        sendtoworkspaceButton  matlab.ui.control.Button
        secButton_2            matlab.ui.control.Button
        zoom_in                matlab.ui.control.Button
        zoom_out               matlab.ui.control.Button
        minButton_2            matlab.ui.control.Button
        LoadDataButton         matlab.ui.control.Button
        loadingstatusLabel     matlab.ui.control.Label
        dataname               matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        current_win % Description
        time % Description
        EEG
        t_frames % Description
        frames % Description
        f_path
        roi_x % Description
        roi_y % Description
        dist
        sleep_score % Description
        n_labeled
        r_dif
        data_name
        vid
        file_dev % portion of file to be read
        t_plot % plotting
        EEG_plot % plotting
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.loadingstatusLabel.Text='Update the code, then press the Load Data';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {616, 616};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {990, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % loading the data %%%%%%%%%%%%%%%%%%%%%%
            %% setting addresses and parameters
            app.loadingstatusLabel.Text='data being loaded...';
            app.loadingstatusLabel.FontColor='g';
            folder_path='Z:\zoologie\HamedData\P1\w0009 juv\04-05-2020'; %%%%%%%%%% read videofile from here
            dir_path_server='Z:\zoologie\HamedData\P1\w0009 juv\04-05-2020\chronic_2020-05-04_20-35-53'; %%%%%%%%
            dir_path='G:\Hamed\zf\P1\w0009eeg'; %%%%%%%%%%%%% save results here
            dir_prefix='133'; %%%%%%%%%%%%%%
            app.file_dev=10; %%%%%%%%%%%%%%%%% which prtion of EEG file you want to read? 10 for ane tenth of the file
            chnl_order=[1 ]; % 1 2 3 4 5 6 8 7 10 9 11 12 13 16 14 15
            
                          
            [~,app.data_name,~]=fileparts(folder_path);
            app.dataname.Text=['Data: ' app.data_name];
            %% preparing the app.EEG and video for the GUI
            
          
            mkdir(dir_path);
            
            % initializing the sleep_score structure
            str_size = 1000;  %number of elements
            sleep_score_(str_size) = struct('time',[],'label',[],'EEG',[],'movement',[]);
            app.sleep_score=sleep_score_;
            app.n_labeled=0;
            
            % app.time frames for app.video frames: loading synchroniying ADC channel
            app.loadingstatusLabel.Text='app.time stamps being loaded...';
            downsamp_ratio=64; % must be a power of 2, as the file reader reads blocks of 1024 samples each app.time
            [ ADC, time_adc, ~]=OpenEphys2MAT_load_save_Data(1, [dir_prefix '_ADC'], downsamp_ratio, app.file_dev,...
                dir_path_server);
            %Extracting synchroniying app.times of frames
            app.loadingstatusLabel.Text='app.time stamps being extracted...';
            [peak_indx]=find(ADC.*(ADC>4));
            jumps_to_new_frame_indx=[diff(peak_indx)>5; true];
            unique_peak_indx=peak_indx(jumps_to_new_frame_indx);
            t_frames_=time_adc(unique_peak_indx);
            clear unique_peak_indx jumps_to_new_frame_indx ADC time_adc peak_indx
            app.t_frames=t_frames_(app.frames);
            
            % load app.EEG as .continuous
            app.loadingstatusLabel.Text='app.EEG being loaded...';
            app.loadingstatusLabel.FontColor='k';
            downsamp_ratio=64; %%%%%%%%% must be a power of 2, as the file reader reads blocks of 1024 samples each app.time
            [ EEG_, app.time, ~]=OpenEphys2MAT_load_save_Data(chnl_order, [dir_prefix '_CH'], downsamp_ratio, app.file_dev,...
                dir_path_server);
            app.EEG=zscore(EEG_);
            app.dist=2;
            app.loadingstatusLabel.Text='app.EEG data loaded';
            app.current_win=0; % first window-center app.time

            
            % Update EEG and video display
           display_eeg_video(app.current_win, app.time, app.EEG, app.UIEEG, app.dist); % update the figures


        end

        % Button pushed function: secButton
        function secButtonPushed(app, event)
            app.current_win=app.current_win+3; % first window-center app.time
            
            % Update EEG and video display
            display_eeg_video(app.current_win, app.time, app.EEG, app.UIEEG, app.dist); % update the figures


        end

        % Button pushed function: minButton
        function minButtonPushed(app, event)
            app.current_win=app.current_win+20*60;
            
            % Update EEG and video display
            display_eeg_video(app.current_win, app.time, app.EEG, app.UIEEG, app.dist); % update the figures


            % deliver the variables to the workspace
            assignin('base','sleep_scores',app.sleep_score);
        end

        % Callback function
        function submitButtonPushed(app, event)
            
        end

        % Button pushed function: WakeButton_2
        function WakeButton_2Pushed(app, event)
            app.n_labeled=app.n_labeled+1;
            label = 'Wake';
            % write the result in the sleep_score
            app.sleep_score(app.n_labeled).time=app.current_win; % center of the time window
            app.sleep_score(app.n_labeled).label=label;
            EEG_ind=(app.time>app.current_win-2.5 & app.time<app.current_win+2.5);
            app.sleep_score(app.n_labeled).EEG=app.EEG(EEG_ind,:);
            app.sleep_score(app.n_labeled).movement=app.r_dif;
        end

        % Button pushed function: REMButton_2
        function REMButton_2Pushed(app, event)
            app.n_labeled=app.n_labeled+1;
            label = 'REM';
            % write the result in the sleep_score
            app.sleep_score(app.n_labeled).time=app.current_win; % center of the time window
            app.sleep_score(app.n_labeled).label=label;
            EEG_ind=(app.time>app.current_win-2.5 & app.time<app.current_win+2.5);
            app.sleep_score(app.n_labeled).EEG=app.EEG(EEG_ind,:);
            app.sleep_score(app.n_labeled).movement=app.r_dif;
        end

        % Button pushed function: SWSButton_2
        function SWSButton_2Pushed(app, event)
            app.n_labeled=app.n_labeled+1;
            label = 'SWS';
            % write the result in the sleep_score
            app.sleep_score(app.n_labeled).time=app.current_win; % center of the time window
            app.sleep_score(app.n_labeled).label=label;
            EEG_ind=(app.time>app.current_win-2.5 & app.time<app.current_win+2.5);
            app.sleep_score(app.n_labeled).EEG=app.EEG(EEG_ind,:);
            app.sleep_score(app.n_labeled).movement=app.r_dif;
        end

        % Button pushed function: ISButton
        function ISButtonPushed(app, event)
            app.n_labeled=app.n_labeled+1;
            label = 'IS';
            % write the result in the sleep_score
            app.sleep_score(app.n_labeled).time=app.current_win; % center of the time window
            app.sleep_score(app.n_labeled).label=label;
            EEG_ind=(app.time>app.current_win-2.5 & app.time<app.current_win+2.5);
            app.sleep_score(app.n_labeled).EEG=app.EEG(EEG_ind,:);
            app.sleep_score(app.n_labeled).movement=app.r_dif;
        end

        % Button pushed function: sendtoworkspaceButton
        function sendtoworkspaceButtonPushed(app, event)
            assignin('base','sleep_scores',app.sleep_score);
        end

        % Button pushed function: secButton_2
        function secButton_2Pushed(app, event)
            app.current_win=app.current_win-3; % first window-center app.time
            
            % Update EEG and video display
            display_eeg_video(app.current_win, app.time, app.EEG, app.UIEEG, app.dist); % update the figures
            
            
        end

        % Button pushed function: zoom_out
        function zoom_outPushed(app, event)
            app.dist=app.dist+.2*app.dist;
            % Update EEG and video display
            display_eeg_video(app.current_win, app.time, app.EEG, app.UIEEG, app.dist); % update the figures
        end

        % Button pushed function: zoom_in
        function zoom_inButtonPushed(app, event)
            app.dist=app.dist-.2*app.dist;
            % Update EEG and video display
            display_eeg_video(app.current_win, app.time, app.EEG, app.UIEEG, app.dist); % update the figures
        end

        % Button pushed function: minButton_2
        function minButton_2Pushed(app, event)
            app.current_win=app.current_win-20*60;
            
            % Update EEG and video display
            display_eeg_video(app.current_win, app.time, app.EEG, app.UIEEG, app.dist); % update the figures
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.minButton = uibutton(app.LeftPanel, 'push');
            app.minButton.ButtonPushedFcn = createCallbackFcn(app, @minButtonPushed, true);
            app.minButton.Text = '>>20min';
            app.secButton = uibutton(app.LeftPanel, 'push');
            app.secButton.ButtonPushedFcn = createCallbackFcn(app, @secButtonPushed, true);
            app.secButton.Text = '> 3sec';
            app.WakeButton_2 = uibutton(app.LeftPanel, 'push');
            app.WakeButton_2.ButtonPushedFcn = createCallbackFcn(app, @WakeButton_2Pushed, true);
            app.WakeButton_2.Text = 'Wake';
            app.REMButton_2 = uibutton(app.LeftPanel, 'push');
            app.REMButton_2.ButtonPushedFcn = createCallbackFcn(app, @REMButton_2Pushed, true);
            app.REMButton_2.Text = 'REM';
            app.SWSButton_2 = uibutton(app.LeftPanel, 'push');
            app.SWSButton_2.ButtonPushedFcn = createCallbackFcn(app, @SWSButton_2Pushed, true);
            app.SWSButton_2.Text = 'SWS';
            app.ISButton = uibutton(app.LeftPanel, 'push');
            app.ISButton.ButtonPushedFcn = createCallbackFcn(app, @ISButtonPushed, true);
            app.ISButton.Text = 'IS';
            app.UIEEG = uiaxes(app.LeftPanel);
            title(app.UIEEG, 'EEG')
            xlabel(app.UIEEG, 'Time (sec)')
            ylabel(app.UIEEG, '')
            app.UIEEG.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIEEG.YTick = [];
            app.sendtoworkspaceButton = uibutton(app.LeftPanel, 'push');
            app.sendtoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @sendtoworkspaceButtonPushed, true);
            app.sendtoworkspaceButton.Text = 'send to workspace';
            app.secButton_2 = uibutton(app.LeftPanel, 'push');
            app.secButton_2.ButtonPushedFcn = createCallbackFcn(app, @secButton_2Pushed, true);
            app.secButton_2.Text = '3sec <';
            app.zoom_in = uibutton(app.LeftPanel, 'push');
            app.zoom_in.ButtonPushedFcn = createCallbackFcn(app, @zoom_inButtonPushed, true);
            app.zoom_in.Text = '+';
            app.zoom_out = uibutton(app.LeftPanel, 'push');
            app.zoom_out.ButtonPushedFcn = createCallbackFcn(app, @zoom_outPushed, true);
            app.zoom_out.Text = '-';
            app.minButton_2 = uibutton(app.LeftPanel, 'push');
            app.minButton_2.ButtonPushedFcn = createCallbackFcn(app, @minButton_2Pushed, true);
            app.minButton_2.Text = '20min<<';
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.loadingstatusLabel = uilabel(app.LeftPanel);
            app.loadingstatusLabel.Text = 'loading status';
            app.dataname = uilabel(app.LeftPanel);
            app.dataname.Text = 'data name';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end